classdef PDProfiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        ApplicationLogLabel            matlab.ui.control.Label
        VersionLabel                   matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        Panel_3                        matlab.ui.container.Panel
        SaveNormalizationButton        matlab.ui.control.Button
        Step4Instructions              matlab.ui.control.Label
        Step4Label                     matlab.ui.control.Label
        BrowseImageButton              matlab.ui.control.Button
        OpenFieldImageEditField        matlab.ui.control.EditField
        OpenFieldImageEditFieldLabel   matlab.ui.control.Label
        BrowseIntensityButton          matlab.ui.control.Button
        PDIPIntensityProfileEditField  matlab.ui.control.EditField
        PDIPIntensityProfileEditFieldLabel  matlab.ui.control.Label
        BrowseNormalizationButton      matlab.ui.control.Button
        NormalizationProfileEditField  matlab.ui.control.EditField
        NormalizationProfileEditFieldLabel  matlab.ui.control.Label
        Step3Label                     matlab.ui.control.Label
        Step2Instructions              matlab.ui.control.Label
        DownloadDICOMButton            matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Step2Label                     matlab.ui.control.Label
        Step1Label                     matlab.ui.control.Label
        Copyright                      matlab.ui.control.Label
        TitleLabel                     matlab.ui.control.Label
    end

    
    properties (Access = private)
        path % Description
        info % Description
        currentCalibration % Description
        targetProfile % Description
        measuredImage % Description
        measuredProfile % Description
        newProfile % Description
        plotLegend % Description
    end
    
    methods (Access = private)
        
        function CalculateCorrectedProfile(app)
            
            % If all three inputs are loaded
            if ~isempty(app.currentCalibration) && ~isempty(app.targetProfile) ...
                    && ~isempty(app.measuredProfile)

                % Calculate new calibration profile from target/measured profile difference
                app.newProfile = app.currentCalibration;
                app.newProfile(:,4) = app.currentCalibration(:,4) .* interp1(app.targetProfile(:,1), ...
                    app.targetProfile(:,4), app.currentCalibration(:,1), 'linear', app.targetProfile(end,4)) ...
                    ./ interp1(app.measuredProfile(:,1), app.measuredProfile(:,2), ...
                    app.currentCalibration(:,1), 'linear', app.targetProfile(end,4));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Define version number
            version = '1.0.0';
            set(app.VersionLabel, 'Text', ['Version ', version]);

            % Determine path of current application
            [path, ~, ~] = fileparts(mfilename('fullpath')); %#ok<*ADPROP> 

            % Set current directory to location of this application
            cd(path);
            app.path = path;

            % Get MATLAB/OS versions, see LoadVersionInfo for more details
            app.info = LoadVersionInfo;

            % Store program and MATLAB/etc version information as a string cell array
            string = {'PDProfiler Panel Normalization Correction Tool'
                sprintf('Version: %s (%s)', version, app.info{6});
                sprintf('Author: Mark Geurts <mark.w.geurts@gmail.com>');
                sprintf('MATLAB Version: %s', app.info{2});
                sprintf('MATLAB License Number: %s', app.info{3});
                sprintf('Operating System: %s', app.info{1});
                sprintf('CUDA: %s', app.info{4});
                sprintf('Java Version: %s', app.info{5})
            };

            % Add dashed line separators
            separator = repmat('-', 1,  size(char(string), 2));
            string = sprintf('%s\n', separator, string{:}, separator);
    
            % Initialize data structures
            app.currentCalibration = [];
            app.targetProfile = [];
            app.measuredImage = [];
            app.measuredProfile = [];
            app.newProfile = [];

            % Initialize plots
            set(app.UIAxes, 'Visible', false);
            app.plotLegend = {'Current Normalization', ...
                    'PDIP Intensity Profile', ...
                    'Measured Open Field Image', ...
                    'Corrected Normalization'};
            set(app.UIAxes_2, 'Visible', false);

            % Log start
            Event(string, 'INIT', app.UITable);
            Event('PDProfiler GUI initialization completed successfully');

            % Clean up
            clear path separator string;
        end

        % Button pushed function: DownloadDICOMButton
        function DownloadDICOMButtonPushed(app, event)

        end

        % Button pushed function: BrowseNormalizationButton
        function BrowseNormalizationButtonPushed(app, event)
            
            % Request the user to select the normalization file
            Event('UI window opened to select normalization file');
            [name, path] = uigetfile({'*.cdp', 'W2CAD File (*.cdp)'}, ...
                'Select the Normalization', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.NormalizationProfileEditField, 'Value', name);

                % Load file
                app.currentCalibration = ...
                    sortrows(LoadCDP(fullfile(path, name)));

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path;
        end

        % Button pushed function: BrowseIntensityButton
        function BrowseIntensityButtonPushed(app, event)
            
            % Request the user to select the intensity file
            Event('UI window opened to select intensity file');
            [name, path] = uigetfile({'*.cdp', 'W2CAD File (*.cdp)'}, ...
                'Select the Intenity Profile', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.PDIPIntensityProfileEditField, 'Value', name);

                % Load file
                app.targetProfile = ...
                    sortrows(LoadCDP(fullfile(path, name)));

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path;
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            
            % Request the user to select the image file
            Event('UI window opened to select image file');
            [name, path] = uigetfile({'*.dxf', 'DXF File (*.dxf)'}, ...
                'Select the Open Field Image', app.path); %#ok<*ADPROPLC> 

            % If the user selected a file
            if ~isequal(name, 0)

                % Log event
                Event(['User selected ', fullfile(path, name)]);

                % Update default path
                Event(['Default file path updated to ', path]);
                app.path = path;

                % Set UI field with filename
                set(app.OpenFieldImageEditField, 'Value', name);

                % Load image file
                [~, res, app.measuredImage] = LoadDXF(fullfile(path, name));

                % Clip image borders
                image = app.measuredImage(11:end-11, 11:end-11);

                % Find symmetric center profile of measured image
                [~, app.measuredProfile(:,2)] = ImageSymmetricCenter(image);
                app.measuredProfile(:,1) = (0:length(app.measuredProfile) ...
                    - 1) * res(1);

                % Calculate corrected profile, if able
                CalculateCorrectedProfile(app);

                % Update profile plot
                PlotNormalizedProfiles({app.currentCalibration, ...
                    app.targetProfile, ...
                    app.measuredProfile, ...
                    app.newProfile}, ...
                    app.UIAxes_2, 'first');
                set(app.UIAxes_2, 'Visible', true);
                legend(app.UIAxes_2, app.plotLegend, 'Location', 'southwest');

            % Otherwise the user did not select a file
            else
                Event('User cancelled file selection');
            end

            % Clear temporary variables
            clear name path res image;
        end

        % Button pushed function: SaveNormalizationButton
        function SaveNormalizationButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Portal Dosimetry Profiler';
            app.Copyright = uilabel(app.UIFigure);
            app.Copyright.Text = 'Copyright (c) 2022 Aspirus Health. Authored by Mark Geurts';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Step1Label = uilabel(app.Panel_3);
            app.Step1Label.Text = 'Step 1: Measure open field';
            app.Step2Label = uilabel(app.Panel_3);
            app.Step2Label.Text = 'Step 2: Retrieve input files from system';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = 'Download the provided DICOM file an deliver it with your MV panel in the dosimetry position.';
            app.DownloadDICOMButton = uibutton(app.Panel_3, 'push');
            app.DownloadDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDICOMButtonPushed, true);
            app.DownloadDICOMButton.Text = 'Download DICOM';
            app.Step2Instructions = uilabel(app.Panel_3);
            app.Step2Instructions.Text = 'Save the below files from your PDIP model and Portal Dosimetry application. The tool accepts the PVA normalization profile and PDIP intensity profile as W2CAD (.cdp) files, and the open field as DXF or DICOM files.';
            app.Step3Label = uilabel(app.Panel_3);
            app.Step3Label.Text = 'Step 3: Select the input files';
            app.NormalizationProfileEditFieldLabel = uilabel(app.Panel_3);
            app.NormalizationProfileEditFieldLabel.Text = 'Normalization Profile';
            app.NormalizationProfileEditField = uieditfield(app.Panel_3, 'text');
            app.NormalizationProfileEditField.Value = 'Choose a .cdp file';
            app.BrowseNormalizationButton = uibutton(app.Panel_3, 'push');
            app.BrowseNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseNormalizationButtonPushed, true);
            app.BrowseNormalizationButton.Text = 'Browse';
            app.PDIPIntensityProfileEditFieldLabel = uilabel(app.Panel_3);
            app.PDIPIntensityProfileEditFieldLabel.Text = 'PDIP Intensity Profile';
            app.PDIPIntensityProfileEditField = uieditfield(app.Panel_3, 'text');
            app.PDIPIntensityProfileEditField.Value = 'Choose a .cdp file';
            app.BrowseIntensityButton = uibutton(app.Panel_3, 'push');
            app.BrowseIntensityButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseIntensityButtonPushed, true);
            app.BrowseIntensityButton.Text = 'Browse';
            app.OpenFieldImageEditFieldLabel = uilabel(app.Panel_3);
            app.OpenFieldImageEditFieldLabel.Text = 'Open Field Image';
            app.OpenFieldImageEditField = uieditfield(app.Panel_3, 'text');
            app.OpenFieldImageEditField.Value = 'Choose a .dxf or .dcm file';
            app.BrowseImageButton = uibutton(app.Panel_3, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse';
            app.Step4Label = uilabel(app.Panel_3);
            app.Step4Label.Text = 'Step 4: Save Corrected Normalization';
            app.Step4Instructions = uilabel(app.Panel_3);
            app.Step4Instructions.Text = 'Save the new corrected normalization file and re-normalize your MV panel with it in PVA Calibration.';
            app.SaveNormalizationButton = uibutton(app.Panel_3, 'push');
            app.SaveNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNormalizationButtonPushed, true);
            app.SaveNormalizationButton.Text = 'Save Normalization';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel_2);
            title(app.UIAxes_2, 'Profiles')
            xlabel(app.UIAxes_2, 'Position (mm)')
            ylabel(app.UIAxes_2, 'Normalized Value')
            zlabel(app.UIAxes_2, 'Z')
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'Version 1.0.0';
            app.Panel = uipanel(app.UIFigure);
            app.ApplicationLogLabel = uilabel(app.Panel);
            app.ApplicationLogLabel.Text = 'Application Log';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDProfiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end